classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        CapturaTab                   matlab.ui.container.Tab
        ListadesealesgeneradasPanel  matlab.ui.container.Panel
        ListBox                      matlab.ui.control.ListBox
        ListBoxLabel                 matlab.ui.control.Label
        ReproducirButton             matlab.ui.control.Button
        ImportarsealPanel            matlab.ui.container.Panel
        ImportarButton               matlab.ui.control.Button
        GenerarsealPanel             matlab.ui.container.Panel
        GenerarButton                matlab.ui.control.Button
        TipodesealDropDown           matlab.ui.control.DropDown
        TipodesealDropDownLabel      matlab.ui.control.Label
        CapturasealPanel             matlab.ui.container.Panel
        GuardarButton                matlab.ui.control.Button
        GrabarButton                 matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        FiltradoTab                  matlab.ui.container.Tab
        FiltrarButton                matlab.ui.control.Button
    end


    properties (Access = public)
        x_in % Señal de audio
        sample_rate % Frecuencia de muestreo
        valor % Opción seleccionada en el dropdown
    end

    properties (Access = private)
    signalsData = {};  % Cell array to store signals and sample rates
    end

    methods (Access = private)
        function results = convolucion(app,x,h,bloqsize,fs)
            ni_x=0;
            Lx= length(x);
            nf_x=Lx+ni_x-1;
            
            ni_h=0;
            Lh=length(h);
            nf_h=Lh+ni_h-1;
            
            Ly=Lx+Lh-1;
            ni_y=ni_x+ni_h;
            nf_y=nf_x+nf_h;
            y=zeros(1,Ly);
            
            Lxb = bloqsize;
            
            % Tamaño salida de bloque yb(n)
            Lyb=Lxb+Lh-1 ;
            % Primer bloque
            xb=x(1 : Lxb);
            
            y(1 : Lyb)=conv(h,xb);

            % Número de bloques 
            Nb= floor(Lx/Lxb);
            
            for k=2: Nb
                t= 0:1/fs:(k-1)/fs;%vector de tiempos para graficar
                xb=x(1+(k-1)* Lxb :  Lxb*k ) ;
                yb=conv(h,xb);   
                y( 1+(k-1)*Lxb : (k-1)*Lxb+ Lyb)= y( 1+(k-1)*Lxb : (k-1)*Lxb+ Lyb) + yb;
                
                % Visualizar salida por bloques
                stem(app.UIAxes_3,y); 
                xlim(app.UIAxes_3, [0 length(x)]);
                %xlabel('n'); title('y(n) por bloques'); ylim([-6, 7])
                pause(0.05)  
            end 
            
            % Calcular la convolución para el bloque incompleto y adicionarlo a y(n)     
            Lxr= mod(Lx,Lxb); 
            
            % verificar si la longitud de x es múltiplo de Nb
            if Lxr ~= 0 % Si hay un bloque incomplet
                xbr=x(1+ Nb*Lxb : Lx) ;
                ybr=conv(h,xbr);
                y( 1+Nb*Lxb: Ly)= y( 1+Nb*Lxb: Ly) + ybr;   
                stem(app.UIAxes_3,y); 
            end 
            results=y;
            app.filtradaFs= fs;
        end
        
        function results = ec_diference(app,x,a,b, fs)
 
            N=length(a);     % Orden N 
            M=length(b)-1;   % Orden M
            
            
            % Señal de entrada x(n)
            Lx=length(x);
            
            y=zeros(1,Lx); % Asignar memoria a y(n)
            v=zeros(1,Lx);  % Asignar memoria a v(n)
            
            for  n=0:Lx-1
                
                m=n+1;
                
                % Calcular v(n) 
                temp_v=0;
                
                for k=0:M
                    
                    kk=k+1;
                    ind= n-k+1;
                
                    if  ind >= 1 && ind < Lx-1
                        %n_k= m-kk+1;  % ajuste del indice de x(n-k) para evitar salir rango
                        temp_v=temp_v+b(kk)*x(ind);
                    end
                end
                v(m)=temp_v;
                
                temp_y=0;
                for k=1:N
                   
                    ind= n-k+1;
                
                    if  ind >= 1 && ind < N
                        %n_k= m-kk+1;  % ajuste del indice de x(n-k) para evitar salir rango
                        temp_y=temp_y+a(k)*y(ind);
                    end
            
                end
                y(m)=temp_y+v(m); % Respuesta total
                
            end   
        n=0:Lx-1;
        stem(app.UIAxes_3,n,y)   
            
        results=y;    
        app.filtradaFs= fs;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
              [app.x_in, app.sample_rate] = grabar_pushed_button(app.UIAxes);
        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
             reproducir_pushed_button(app.x_in, app.sample_rate);
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            % Ingresar los parámetros para grabar el audio
            prompt = {'Nombre del archivo:'};
            dlgtitle = 'Guardar archivo';
            dims = [1 35];
            definput = {'archivo'};
            answer = inputdlg(prompt, dlgtitle, dims, definput);
            
            % Recuperar parámetros de la caja de diálogo
            nombre_archivo = (answer{1});

            nombre_archivo_completo = [nombre_archivo, '.wav'];
            disp(nombre_archivo_completo);
            audiowrite(nombre_archivo_completo, app.x_in, app.sample_rate);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: ImportarButton
        function ImportarButtonPushed(app, event)
            [filename, path] = uigetfile({'*.mp3;*.wav'});
            figure(app.UIFigure);
            fullFileName = fullfile(path, filename);  % Construye la ruta completa del archivo
            
            % Recuperar los datos importados
            [app.x_in, app.sample_rate] = audioread(fullFileName);
            % sound(data, sample_rate);

            % Graficar Canal 1 en UIAxes de App Designer
            plot(app.UIAxes, app.x_in(:,1), 'v');
            title(app.UIAxes, 'Señal Estereofónica: Canal 1');
            xlabel(app.UIAxes, 'n');
            grid(app.UIAxes, 'on');
            add_to_signals(app, app.x_in, app.sample_rate,3);
            
        end

        % Value changed function: TipodesealDropDown
        function TipodesealDropDownValueChanged(app, event)
            app.valor = app.TipodesealDropDown.Value;
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            [app.x_in, app.sample_rate] = generar_funciones(app.UIAxes, app.valor);
            % Suppose 'signal' is your generated/recorded signal and 'Fs' is the sample rate
            % Increment the signal count and add to the cell array
            num_signals = length(app.signalsData) + 1;
            app.signalsData{num_signals} = {app.x_in, app.sample_rate};
            
            % Add an entry to the ListBox to represent this signal
            signalName = ['Signal ', num2str(num_signals)];  % Name the signal (e.g., "Signal 1", "Signal 2")
            app.ListBox.Items{num_signals} = signalName;     % Add to the List Box

        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            % Callback for when a signal is selected from the List Box
            selectedSignalIndex = app.ListBox.Value;  % Get the selected index
            signalData = app.signalsData{selectedSignalIndex};  % Retrieve signal and sample rate
        
            % 'signalData' contains {signal, sample rate}
            selectedSignal = signalData{1};  % The actual signal data
            selectedSampleRate = signalData{2};  % The sample rate
            
            % Now you can do something with the selected signal and sample rate, like playing it
            sound(selectedSignal, selectedSampleRate);

        end

        % Button pushed function: FiltrarButton
        function FiltrarButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CapturaTab = uitab(app.TabGroup);
            app.CapturaTab.Title = 'Captura';
            app.UIAxes = uiaxes(app.CapturaTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.CapturasealPanel = uipanel(app.CapturaTab);
            app.CapturasealPanel.Title = 'Captura señal';
            app.GrabarButton = uibutton(app.CapturasealPanel, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.GuardarButton = uibutton(app.CapturasealPanel, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.GenerarsealPanel = uipanel(app.CapturaTab);
            app.GenerarsealPanel.Title = 'Generar señal';
            app.TipodesealDropDownLabel = uilabel(app.GenerarsealPanel);
            app.TipodesealDropDownLabel.Text = 'Tipo de señal';
            app.TipodesealDropDown = uidropdown(app.GenerarsealPanel);
            app.TipodesealDropDown.Items = {'---', 'Chirp', 'Seno', 'Coseno', 'Diente de sierra', 'Rampa', 'Escalón'};
            app.TipodesealDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodesealDropDownValueChanged, true);
            app.TipodesealDropDown.Value = '---';
            app.GenerarButton = uibutton(app.GenerarsealPanel, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.ImportarsealPanel = uipanel(app.CapturaTab);
            app.ImportarsealPanel.Title = 'Importar señal';
            app.ImportarButton = uibutton(app.ImportarsealPanel, 'push');
            app.ImportarButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarButtonPushed, true);
            app.ImportarButton.Text = 'Importar';
            app.ReproducirButton = uibutton(app.CapturaTab, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.ListadesealesgeneradasPanel = uipanel(app.CapturaTab);
            app.ListadesealesgeneradasPanel.Title = 'Lista de señales generadas';
            app.ListBoxLabel = uilabel(app.ListadesealesgeneradasPanel);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.ListadesealesgeneradasPanel);
            app.ListBox.Items = {''};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = '';
            app.FiltradoTab = uitab(app.TabGroup);
            app.FiltradoTab.Title = 'Filtrado';
            app.FiltrarButton = uibutton(app.FiltradoTab, 'push');
            app.FiltrarButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrarButtonPushed, true);
            app.FiltrarButton.Text = 'Filtrar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end